classdef CraniobotCourier_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GUI                    matlab.ui.Figure
        ProbingMenu            matlab.ui.container.Menu
        probeCircleMenu        matlab.ui.container.Menu
        probeSpiralMenu        matlab.ui.container.Menu
        probeDotMenu           matlab.ui.container.Menu
        probeWindowMenu        matlab.ui.container.Menu
        clearSet               matlab.ui.container.Menu
        editSet                matlab.ui.container.Menu
        MillingMenu            matlab.ui.container.Menu
        millMenu               matlab.ui.container.Menu
        SucktionMenu           matlab.ui.container.Menu
        sucktionSpiralMenu     matlab.ui.container.Menu
        toolMenu               matlab.ui.container.Menu
        connectionPanel        matlab.ui.container.Panel
        portMenu               matlab.ui.control.DropDown
        portTextBox            matlab.ui.control.Label
        connectButton          matlab.ui.control.StateButton
        refreshButton          matlab.ui.control.Button
        consoleWindow          matlab.ui.control.ListBox
        commandLine            matlab.ui.control.EditField
        commandLineTextBox     matlab.ui.control.Label
        MotionButtonsGrp       matlab.ui.container.Panel
        linearStepGrp          matlab.ui.container.ButtonGroup
        linearStepSizeTextBox  matlab.ui.control.Label
        linearStepSize         matlab.ui.control.EditField
        MillimeterButton       matlab.ui.control.RadioButton
        InchesButton           matlab.ui.control.RadioButton
        XPlus                  matlab.ui.control.Button
        XMinus                 matlab.ui.control.Button
        YPlus                  matlab.ui.control.Button
        YMinus                 matlab.ui.control.Button
        ZPlus                  matlab.ui.control.Button
        ZMinus                 matlab.ui.control.Button
        consoleTextBox         matlab.ui.control.Label
        commonCommandsGrp      matlab.ui.container.Panel
        setOriginButton        matlab.ui.control.Button
        moveToOriginButton     matlab.ui.control.Button
        resetXButton           matlab.ui.control.Button
        resetYButton           matlab.ui.control.Button
        resetZButton           matlab.ui.control.Button
        homeAllAxes            matlab.ui.control.Button
        clearButton            matlab.ui.control.Button
        resetButton            matlab.ui.control.Button
        G55Button              matlab.ui.control.Button
        G56Button              matlab.ui.control.Button
        G55Text                matlab.ui.control.EditField
        G56Text                matlab.ui.control.EditField
        drill_replace          matlab.ui.control.Button
        plate_replace          matlab.ui.control.Button
        systemReportPanel      matlab.ui.container.Panel
        workPositionTextBox    matlab.ui.control.Label
        machineStateTextBox    matlab.ui.control.Label
        clearWindow            matlab.ui.control.Button
        fileManagerGrp         matlab.ui.container.Panel
        chooseFileButton       matlab.ui.control.Button
        sendFileButton         matlab.ui.control.Button
        pauseButton            matlab.ui.control.StateButton
        abortButton            matlab.ui.control.StateButton
        fileTextBox            matlab.ui.control.Label
        progressBar            matlab.ui.control.Label
    end

    
    methods (Access = private)
        function BytesAvailable(app, device, ~)
            % Objective: Computer will automatically read input from serial device
            % asynchronously. This function assumes all incoming data is formatted as json
            
            % Variables:
            % GUI           Handle to GUI object
            % handles       struct containing all handles within the GUI
            % json          JSON structure
            
            GUI = findobj(0,'Tag','GUI'); % find GUI since hObject isn't passed into this function
            handles = guidata(GUI); % get handles
            consoleWindow = handles.consoleWindow;
            newData = fscanf(device); % get input serial data
            
            % If input msg is in json format (first char is {'{'), then analyze the json
            if newData(1) == '{'
                json = jsondecode(newData);
                interpretJson(app, json);
            else % print anything else to the console window
                data = cellstr(get(handles.consoleWindow,'String'));
                data{end+1} = newData;
                set(handles.consoleWindow,'String',data,...
                             'Value',length(data));
            end
        end
        
        function interpretJson(app, json)
            % Objective: This function takes in a json structure, loops through each
            % element, and will call functions depending on the elements of the json msg.
            
            % Variables:
            % GUI        handle to figure window
            % handles    structure with handles and user data of GUI (see GUIDATA)
            % json       struct containing name-value pairs or nested structs
            % field      data containers within a struct (similar to array element)
            
            GUI = findobj(0,'Tag','GUI'); % find GUI since hObject isn't passed into this function
            handles = guidata(GUI); % get handles
            % get console text
            data = cellstr(get(handles.consoleWindow,'String'));
            
            % get field names of json text
            fields = fieldnames(json);
            
            % Look at each field of the json struct and respond accordingly to the
            % information
            for i = 1:numel(fields)
                % Ignore empty fields
                if ~isempty(json.(fields{i}))
                    switch fields{i}
                        case 'r'   % Reports
                            % If the controller sends a json report, increment the Send Button's
                            % 'userdata' variable (aka, the Linemode protocol's linesToSend
                            % variable (used in sendFileButton_Callback)).
                            SendButton = findobj(gcf,'Tag','sendFileButton');
                            if get(SendButton,'userdata') < 3
                                set(SendButton,'userdata',get(SendButton,'userdata')+1);
                            end
            
                            % if the report has children objects, interpret them
                            if ~isempty(json.r)
                                interpretJson(app, json.r);
                            end
                        case 'sr'  % Status Reports
                            % If the controller sends a status report, update the GUI's
                            % system report window
                            statusReportJson(app, json.sr);
                        case 'gc'  % GCode echo
                            % Print the interpreted Gcode to the console window
                            handles.lastGc = json.gc;
                            consoleWindow = handles.consoleWindow;
                            data = cellstr(get(consoleWindow,'String'));
                            data{end+1} = handles.lastGc;
                            set(consoleWindow,'String',data,...
                                              'Value',length(data));
                        case 'n'   % Line number response (tinyG).
                            % If line number are present in a gcode file, use them to
                            % calculate file completion
                            handles.line = json.(fields{i});
                            set(handles.progressBar,'String',{'Progress:',...
                            strcat(num2str(100*handles.line/handles.MaxLine),'%')});
                        case 'prb' % Probe Reports
                            % If the controller sends a probe report, update the skullPoints array
                            updateSkullPoints(app, json.prb);
                        case 'f'   % Footers
                            % Display status (f is a 1x3 array; element 2 is the status number
                            text = statusCodes(json.f(2));
                            if text ~= "STAT_OK" % suppress 'ok' messages
                                data = cellstr(get(handles.consoleWindow,'String'));
                                data{end+1} = text; %concatenate json-string with the old data
                                set(handles.consoleWindow,'String',data,...
                                             'Value',length(data));
                            end
                        case 'er'  % Error Messages
                            % Print error message on console and update machine status
                            text = json.er.msg;
                            set(handles.machineStateTextBox,'String',...
                                {"Machine State:","See Error Message"});
                            data = cellstr(get(handles.consoleWindow,'String'));
                            data{end+1} = strcat("MSG: ",text); %concatenate json-string with the old data
                            set(handles.consoleWindow,'String',data,...
                                         'Value',length(data));
                        otherwise  % Any other information is probably relevant to the user,
                                   % so we print every name-value pair in message
                            % iterate through every name-value pair in message
                            name = fields{i};
                            value = json.(fields{i});
                            % if nested json, use recursion to go through the different
                            % nests
                            if isstruct(value)
                                data{end+1} = sprintf("%-s:",name);
                                % print to console
                                set(handles.consoleWindow,'String',data,...
                                     'Value',length(data));
            
                                interpretJson(app, value);
                            else
                                % convert to string (if not already string) and add to
                                % console text (note, 32 is ascii for 'space'
                                value = string(value);
                                % ignore tool table entry reports since they can be seen in
                                % the tool table menu
                                if contains(name,"tt")
                                    continue;
                                end
            
                                data{end+1} = sprintf("%-s: %+20s",name,value);
                                % print to console
                                set(handles.consoleWindow,'String',data,...
                                     'Value',length(data));
                            end
                    end
                end
            end
        end
        
        function millSkullButton_Callback(app, hObject, eventdata, handles)
            % Objective: Take the recorded skull points and generate a milling filling
            % accordingly
            
            % hObject    handle to probeSkullButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            figHandles = guidata(findobj(0,'Tag','millWindow')); % get handles
            handles    = guidata(findobj(0,'Tag','GUI'));
            
            if isempty(handles.skullPoints)
                uiwait( errordlg('Skull has not been probed yet.',...
                                 'Input Error', 'modal') );
            else
                xVals       = handles.skullPoints(:,1);
                yVals       = handles.skullPoints(:,2);
                zVals       = handles.skullPoints(:,3);
                depth       = str2double(get(figHandles.depthTextBox,'String'));
                thickness   = str2double(get(figHandles.thicknessTextBox,'String'));
                feedrate    = str2double(get(figHandles.feedrateTextBox,'String'));
            
                if isempty(depth) || isempty(thickness) || isempty(feedrate)
                    uiwait( errordlg('Missing input parameter...',...
                                     'Input Error', 'modal') );
                else
                    close;
                    millProbedPoints(xVals,yVals,zVals,thickness,depth,feedrate);
                end
            end
        end
        
        function probeCircleButton_Callback(app, hObject, eventdata, handles)
            % Objective: Take user input values for desired chamber location (in stereotaxic
            % coordinates), probe the skull, and record the xyz coordinates of each point to
            % produce a tool path
            
            % hObject    handle to probeSkullButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % @val       axis value stored in text box by user
            
            figHandles = guidata(findobj(0,'Tag','ProbeCircleWindow')); % get handles
            xVal   = str2double(get(figHandles.chamberXTextBox,'String'));
            yVal   = str2double(get(figHandles.chamberYTextBox,'String'));
            zVal   = str2double(get(figHandles.chamberZTextBox,'String'));
            diaVal = str2double(get(figHandles.chamberDiameterTextBox,'String'));
            speed  = str2double(get(figHandles.probeSpeedTextBox,'String'));
            
            % if an input is missing, throw an error message
            if isempty(xVal) || isempty(yVal) || isempty(zVal) || isempty(diaVal)
                uiwait( errordlg('Missing input parameter...',...
                                 'Input Error', 'modal') );
            else
                GUI     = findobj(0,'Tag','GUI'); % find GUI since it isn't passed into this function
                handles = guidata(GUI); % get GUI handles (not probe menu handles)
                close;
                probeCircle(diaVal,xVal,yVal,zVal,speed);
            end
        end
        
        function probeDotButton_Callback(app, hObject, eventdata, handles)
            % Objective: Take user input values for desired chamber location (in stereotaxic
            % coordinates), probe the skull, and record the xyz coordinates of each point to
            % produce a tool path
            
            % hObject    handle to probeSkullButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % @val       axis value stored in text box by user
            
            figHandles = guidata(findobj(0,'Tag','ProbeDotWindow')); % get handles
            xVal   = str2double(get(figHandles.chamberXDTextBox,'String'));
            yVal   = str2double(get(figHandles.chamberYDTextBox,'String'));
            zVal   = str2double(get(figHandles.chamberZDTextBox,'String'));
            speed  = str2double(get(figHandles.probeSpeedDTextBox,'String'));
            
            % if an input is missing, throw an error message
            if isempty(xVal) || isempty(yVal) || isempty(zVal)
                uiwait( errordlg('Missing input parameter...',...
                                 'Input Error', 'modal') );
            else
                GUI     = findobj(0,'Tag','GUI'); % find GUI since it isn't passed into this function
                handles = guidata(GUI); % get GUI handles (not probe menu handles)
                close;
                probeDot(xVal,yVal,zVal,speed);
            end
        end
        
        function probeSpiralButton_Callback(app, hObject, eventdata, handles)
            % Objective: Take user input values for desired chamber location (in stereotaxic
            % coordinates), probe the skull, and record the xyz coordinates of each point to
            % produce a tool path
            
            % hObject    handle to probeSkullButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % @val       axis value stored in text box by user
            
            figHandles = guidata(findobj(0,'Tag','ProbeSpiralWindow')); % get handles
            xVal   = str2double(get(figHandles.chamberXSTextBox,'String'));
            yVal   = str2double(get(figHandles.chamberYSTextBox,'String'));
            zVal   = str2double(get(figHandles.chamberZSTextBox,'String'));
            suckVal = str2double(get(figHandles.chamberSuckTextBox,'String'));
            diaVal = str2double(get(figHandles.chamberDiameterSTextBox,'String'));
            speed  = str2double(get(figHandles.probeSpeedSTextBox,'String'));
            
            % if an input is missing, throw an error message
            if isempty(xVal) || isempty(yVal) || isempty(zVal) || isempty(diaVal)
                uiwait( errordlg('Missing input parameter...',...
                                 'Input Error', 'modal') );
            else
                GUI     = findobj(0,'Tag','GUI'); % find GUI since it isn't passed into this function
                handles = guidata(GUI); % get GUI handles (not probe menu handles)
                close;
                probeSpiral(suckVal,diaVal,xVal,yVal,zVal,speed);
            end
        end
        
        function probeWindowButton_Callback(app, hObject, eventdata, handles)
            % hObject    handle to probeSkullButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            figHandles = guidata(findobj(0,'Tag','ProbeWindowWindow')); % get handle to window
            max_steps  = str2double(get(figHandles.maxStepTextBox,'String'));
            if isempty(max_steps)
                uiwait( errordlg('Missing input parameter...',...
                                 'Input Error', 'modal') );
            else
                close
                pointGen(max_steps);
            end
        end
        
        function resetAButton_Callback(app, hObject, eventdata, handles)
            % hObject    handle to resetAButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Set controller position
            fprintf(handles.device,'G28.3 A0; (Reset A Axis)');
        end
        
        function resetBButton_Callback(app, hObject, eventdata, handles)
            % hObject    handle to resetBButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Set controller position
            fprintf(handles.device,'G28.3 B0; (Reset B Axis)');
        end
        
        function resetCButton_Callback(app, hObject, eventdata, handles)
            % hObject    handle to resetCButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Set controller position
            fprintf(handles.device,'G28.3 C0; (Reset C Axis)');
        end
        
        function statusReportJson(app, SR)
            % Objective: take a struct containing a json status report (SR), extract
            % the name-value pairs, and update the GUI with relevant information.
            
            % Variables:
            % SR         Status Report struct containing name-value pairs
            % handles    structure with handles and user data (see GUIDATA)
            % SRFields   list of fields in the status report
            
            handles = guidata(findobj(0,'Tag','GUI')); % get handles
            
            % get field names of json text
            SRfields = fieldnames(SR);
            
            % update the GUI variables that are called in the Status Report
            for i = 1:numel(SRfields)
                switch SRfields{i}
                    case "line"     % G2core line response
                        handles.line = SR.(SRfields{i});
                        set(handles.progressBar,'String',{'Progress:',...
                        strcat(num2str(100*handles.line/handles.MaxLine),'%')});
                    case "coor"
                        handles.coor = SR.(SRfields{i});
                    case "posx"
                        handles.posx = SR.(SRfields{i});
                    case "posy"
                        handles.posy = SR.(SRfields{i});
                    case "posz"
                        handles.posz = SR.(SRfields{i});
                    case "posa"
                        handles.posa = SR.(SRfields{i});
                    case "posb"
                        handles.posb = SR.(SRfields{i});
                    case "posc"
                        handles.posc = SR.(SRfields{i});
                    case "stat"
                        switch SR.(SRfields{i})
                            case 0
                                handles.stat = "INITIALIZING";
                            case 1
                                handles.stat = "READY";
                            case 2
                                handles.stat = "ALARM";
                            case 3
                                % Should be program stop, but it means the same thing and
                                % looks better
                                handles.stat = "READY";
                            case 4
                                handles.stat = "PROGRAM END";
                            case 5
                                handles.stat = "RUN";
                            case 6
                                handles.stat = "HOLD";
                            case 7
                                handles.stat = "PROBE";
                            case 8
                                handles.stat = "CYCLE";
                            case 9
                                handles.stat = "HOMING";
                            case 10
                                handles.stat = "JOG";
                            case 11
                                handles.stat = "INTERLOCK";
                            case 12
                                handles.stat = "SHUTDOWN";
                            case 13
                                handles.stat = "PANIC";
                        end
                    case "units"
                        switch SR.(SRfields{i})
                            case 0
                                handles.units = 0;
                            case 1
                                handles.units = 1;
                        end
                end
            end
            
            if handles.units == 1
                units = 'Work Position (mm):';
            else
                units = 'Work Position (inch):';
            end
            
            guidata(findobj(0,'Tag','GUI'), handles);
            
            coor = strcat('Coord Sys: G',num2str(handles.coor + 53));
            xStr = strcat('X:',32,num2str(handles.posx));
            yStr = strcat('Y:',32,num2str(handles.posy));
            zStr = strcat('Z:',32,num2str(handles.posz));
            aStr = strcat('A:',32,num2str(handles.posa));
            bStr = strcat('B:',32,num2str(handles.posb));
            cStr = strcat('C:',32,num2str(handles.posc));
            positionString = {units,coor,xStr,yStr,zStr,aStr,bStr,cStr};
            stateString = {'Machine State:',handles.stat};
            set(handles.workPositionTextBox,'String',positionString);
            set(handles.machineStateTextBox,'String',stateString);
            
            % Update handles structure
            guidata(findobj(0,'Tag','GUI'), handles);
        end
        
        function sucktionSpiralButton_Callback(app, hObject, eventdata, handles)
            % Objective: Take the recorded skull points and generate a milling filling
            % accordingly
            
            % hObject    handle to probeSkullButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            figHandles = guidata(findobj(0,'Tag','suckWindow')); % get handles
            handles    = guidata(findobj(0,'Tag','GUI'));
            
            if isempty(handles.skullPoints)
                uiwait( errordlg('Skull has not been probed yet.',...
                                 'Input Error', 'modal') );
            else
                suckVal     = str2double(get(figHandles.SchamberSuckTextBox,'String'));
                diaVal      = str2double(get(figHandles.SchamberDiameterSTextBox,'String'));
                xVals       = handles.skullPoints(:,1);
                yVals       = handles.skullPoints(:,2);
                zVals       = handles.skullPoints(:,3);
                depth       = str2double(get(figHandles.SdepthTextBox,'String'));
                thickness   = str2double(get(figHandles.SthicknessTextBox,'String'));
                feedrate    = str2double(get(figHandles.SfeedrateTextBox,'String'));
            
                if isempty(suckVal) || isempty (diaVal) isempty(depth) || isempty(thickness) || isempty(feedrate)
                    uiwait( errordlg('Missing input parameter...',...
                                     'Input Error', 'modal') );
                else
                    close;
                    sucktionSpiral(suckVal,diaVal,xVals,yVals,zVals,thickness,depth,feedrate);
                end
            end
        end
        
        function updateSkullPoints(app, PR)
            % Objective: take a struct containing a json probe report (PR), extract the
            % name-value pair for the probed z-value, and update the skullPoints array
            
            % Variables:
            % PR         struct containing name-value pairs
            % handles    structure with handles and user data (see GUIDATA)
            % PRFields   list of fields in the probe report
            
            handles = guidata(findobj(0,'Tag','GUI')); % get handles
            % get field names of json text
            PRfields = fieldnames(PR);
            
            % update skullPoints array
            for i = 1:numel(PRfields)
                switch PRfields{i}
                    case "z"
                        x = handles.posx;
                        y = handles.posy;
                        z = PR.(PRfields{i}); % - handles.toolTable.ToolOffset(handles.tool);
                        handles.skullPoints(end+1,:) = [x, y, z];
                end
            end
            
            % update handles structure
            guidata(findobj(0,'Tag','GUI'),handles);
        end
        
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Load data for component configuration
            componentData = load('CraniobotCourier_App.mat');
            
            % Set component properties that require runtime configuration
            app.commandLine.UserData = componentData.commandLine.UserData;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function CraniobotCourier_OpeningFcn(app, varargin)
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % Objective: Initializes the GUI and many of its persistent variables, all of
            % which are stored in the 'handles' structue
            
            % Choose default command line output for CraniobotCourier
            handles.output = hObject;
            
            %  port variable
            handles.port = "";
            
            %  path of G-code file that is to be sent to Craniobot
            handles.filePath = {};
            handles.lastGc = "";
            
            %  command line cache (used for selecting previously issued commands)
            handles.cache = {};
            
            %  2D array to store all probed points of skull in form
            % [x1,y1,z1;
            %  x2 y2 z2;...]
            handles.skullPoints = [];
            
            %  "linesToSend" value for Linemode protocol (see g2core wiki)
            sb = findobj(gcf,'Tag','sendFileButton');
            set(sb,'userdata',4);
            
            %  machine state variable
            handles.stat = "DISCONNECTED";
            
            %  CNC position variables
            handles.posx = 0;
            handles.posy = 0;
            handles.posz = 0;
            handles.posa = 0;
            handles.posb = 0;
            handles.posc = 0;
            handles.coor = 1; % 1=G54, 2=G55, etc.
            
            % GCode parameters
            handles.units = 1; % (0|1 - inch|mm). Default: 1.
            
            %  state and position textboxs
            % Note: 32 is the ASCII character for a space
            if handles.units == 1
                units = 'Work Position (mm):';
            else
                units = 'Work Position (inch):';
            end
            coor = strcat('Coord Sys: G',num2str(handles.coor + 53));
            xStr = strcat('X:',32,num2str(handles.posx));
            yStr = strcat('Y:',32,num2str(handles.posy));
            zStr = strcat('Z:',32,num2str(handles.posz));
            aStr = strcat('A:',32,num2str(handles.posa));
            bStr = strcat('B:',32,num2str(handles.posb));
            cStr = strcat('C:',32,num2str(handles.posc));
            positionString = {units,coor,xStr,yStr,zStr,aStr,bStr,cStr};
            stateString = {'Machine State:',handles.stat};
            set(handles.workPositionTextBox,'String',positionString);
            set(handles.machineStateTextBox,'String',stateString);
            
            %  program progress bar
            handles.line    = 1;
            handles.MaxLine = 1;
            set(handles.progressBar,'String',{'Progress:',...
                strcat(num2str(100*handles.line/handles.MaxLine),'%')});
            
            % Read in Tool Table
            filename = fullfile(pwd,'toolTable.csv');
            handles.toolTable = readtable(filename);
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: G55Button
        function G55Button_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to G55Button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %
            % Tool A uses coord sys P2 (G55). We reserve P1 (G54) as a persistent machine
            % coordinate system
            offset = handles.G55Text.String;
            str = strcat("G10 L2 P2 X0 Y0 Z",offset,"A0 B0 C0");
            fprintf(handles.device,str);
            fprintf(handles.device,"G55");
        end

        % Value changed function: G55Text
        function G55Text_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to G55Text (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of G55Text as text
            %        str2double(get(hObject,'String')) returns contents of G55Text as a double
        end

        % Button pushed function: G56Button
        function G56Button_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to G56Button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %
            % Tool B uses coord sys P3 (G56). We reserve P1 (G54) as a persistent machine
            % coordinate system
            offset = handles.G56Text.String;
            str = strcat("G10 L2 P3 X0 Y0 Z",offset,"A0 B0 C0");
            fprintf(handles.device,str);
            fprintf(handles.device,"G56");
        end

        % Value changed function: G56Text
        function G56Text_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to G56Text (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of G56Text as text
            %        str2double(get(hObject,'String')) returns contents of G56Text as a double
        end

        % Window key press function: GUI
        function GUI_WindowKeyPressFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to GUI (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.FIGURE)
            %	Key: name of the key that was pressed, in lower case
            %	Character: character interpretation of the key(s) that was pressed
            %	Modifier: name(s) of the modifier key(s) (i.e., control, shift) pressed
            % handles    structure with handles and user data (see GUIDATA)
            
            shift    = any(strcmp(eventdata.Modifier,'shift'));
            control  = any(strcmp(eventdata.Modifier,'control'));
            alt      = any(strcmp(eventdata.Modifier,'alt'));
            if isempty(eventdata.Modifier)
                % do nothing if no modifier present
            elseif shift && ~control && ~alt
                if strcmp(eventdata.Key,'rightarrow')
                    XPlus_Callback(hObject, eventdata, handles);
                end
                if strcmp(eventdata.Key,'leftarrow')
                    XMinus_Callback(hObject, eventdata, handles);
                end
                if strcmp(eventdata.Key,'uparrow')
                    YPlus_Callback(hObject, eventdata, handles);
                end
                if strcmp(eventdata.Key,'downarrow')
                    YMinus_Callback(hObject, eventdata, handles);
                end
            elseif shift && control && ~alt
                if strcmp(eventdata.Key,'uparrow')
                    ZPlus_Callback(hObject, eventdata, handles);
                end
                if strcmp(eventdata.Key,'downarrow')
                    ZMinus_Callback(hObject, eventdata, handles);
                end
            end
        end

        % Menu selected function: ProbingMenu
        function ProbingMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ProbingMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if isempty(handles.skullPoints)
                set(handles.clearSet,'enable','off');
                set(handles.editSet,'enable','off');
            else
                set(handles.clearSet,'enable','on');
                set(handles.editSet,'enable','on');
            end
        end

        % Button pushed function: XMinus
        function XMinus_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Objective: Sends a gcode command to move the Craniobot incrimentally in the
            % given axis and direction using the step size from the linearStepGrp
            
            % hObject    handle to XMinus (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % send movement command
            stepSize = get(handles.linearStepSize,'String');
            command = string(strcat('G91 G0 X-',stepSize));
            fprintf(handles.device,command); % move to new position
        end

        % Button pushed function: XPlus
        function XPlus_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Objective: Sends a gcode command to move the Craniobot incrimentally in the
            % given axis and direction using the step size from the linearStepGrp
            
            % hObject    handle to XMinus (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % send movement command
            stepSize = get(handles.linearStepSize,'String');
            command = string(strcat('G91 G0 X',stepSize));
            fprintf(handles.device,command); % move to new position
        end

        % Button pushed function: YMinus
        function YMinus_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Objective: Sends a gcode command to move the Craniobot incrimentally in the
            % given axis and direction using the step size from the linearStepGrp
            
            % hObject    handle to YMinus (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % send movement command
            stepSize = get(handles.linearStepSize,'String');
            command = string(strcat('G91 G0 Y-',stepSize));
            fprintf(handles.device,command); % move to new position
        end

        % Button pushed function: YPlus
        function YPlus_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Objective: Sends a gcode command to move the Craniobot incrimentally in the
            % given axis and direction using the step size from the linearStepGrp
            
            % hObject    handle to YPlus (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % send movement command
            stepSize = get(handles.linearStepSize,'String');
            command = string(strcat('G91 G0 Y',stepSize));
            fprintf(handles.device,command); % move to new position
        end

        % Button pushed function: ZMinus
        function ZMinus_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Objective: Sends a gcode command to move the Craniobot incrimentally in the
            % given axis and direction using the step size from the linearStepGrp
            
            % hObject    handle to ZMinus (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % send movement command
            stepSize = get(handles.linearStepSize,'String');
            command = string(strcat('G91 G0 Z-',stepSize));
            fprintf(handles.device,command); % move to new position
        end

        % Button pushed function: ZPlus
        function ZPlus_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Objective: Sends a gcode command to move the Craniobot incrimentally in the
            % given axis and direction using the step size from the linearStepGrp
            
            % hObject    handle to ZPlus (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % send movement command
            stepSize = get(handles.linearStepSize,'String');
            command = string(strcat('G91 G0 Z',stepSize));
            fprintf(handles.device,command); % move to new position
        end

        % Value changed function: abortButton
        function abortButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Objective: Cancel the rest of the file being sent to the Craniobot
            
            fprintf(handles.device,4); % 4 is the ascii character for ctrl-d
            set(handles.pauseButton,'String','Pause','Value',0);
        end

        % Button pushed function: chooseFileButton
        function chooseFileButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Objective: Allow user to choose a G-code (txt) file to be send to the Craniobot
            
            % Key Variables:
            % handles    structure with handles and user data (see GUIDATA)
            
            % open dialog box, get file name and path to file on user computer
            % Note: must be .txt file
            [tempName,tempPath] = uigetfile('.txt');
            
            % if file exists: change text box to file name, enable send/pause/cancel
            % buttons, get number of lines
            if tempName ~= 0
                handles.filePath = strcat(tempPath,tempName);
                set(handles.fileTextBox,'String',tempName);
                set(handles.sendFileButton,'enable','on');
                fid = fopen(handles.filePath);
                lines = textscan(fid,'%s','delimiter','\n');
                fclose(fid);
                handles.MaxLine = length(lines{1});
                % save changes to handles
                guidata(hObject,handles);
            end
        end

        % Button pushed function: clearButton
        function clearButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Objective: used to clear alarms
            
            % hObject    handle to clearButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            fprintf(handles.device,' G90 G0 X0 Y0 Z3; (Move to Milling Position)');
            %fprintf(handles.device,'{"clear":n}');
            %data = cellstr(get(handles.consoleWindow,'String'));
            %data{end+1} = '{"clear":n} (Clear Alarms)'; %concatenate newData with the old data
            %set(handles.consoleWindow,'String',data,...
            %           'Value',length(data));
        end

        % Menu selected function: clearSet
        function clearSet_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to clearSet (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            handles.skullPoints = [];
            guidata(hObject,handles);
        end

        % Button pushed function: clearWindow
        function clearWindow_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Objective: Clears all text from the console window
            
            % Variables:
            % hObject    handle to clearWindow (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % consoleWindow     Handle to ui object
            consoleWindow = handles.consoleWindow;
            set(consoleWindow,'String',{},...
                'Value',1);
        end

        % Value changed function: commandLine
        function commandLine_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to commandLine (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % get handles/set make command line blank. Update cache
            device = handles.device;
            text = string(get(gcbo,'String'));
            handles.cache{end+1} = cellstr(text);
            set(gcbo,'String','');
            
            % send command
            fprintf(device,upper(text));
            guidata(hObject, handles);
        end

        % Value changed function: connectButton
        function connectButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Objective: Open/close a serial connection to the Craniobot and enable buttons
            
            % hObject    handle to connectButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % device     handle to serial object (the Craniobot)
            try
                device = serial(handles.port,'baudRate',115200,...
                                        'Databits', 8,...
                                        'StopBits',1,...
                                        'Parity','none',...
                                        'FlowControl','none',...
                                        'ReadAsyncMode','continuous',...
                                        'Terminator','LF',...
                                        'BytesAvailableFcnMode','terminator',...
                                        'BytesAvailableFcn',@app.BytesAvailable);
            
                % if button is switched "on"
                if get(hObject,'Value')
            
                    fopen(device);
                    % If the serial port fails to open
                    if get(device,'Status') ~= 'open'
                        instrreset;
                        uiwait( errordlg('Missing input parameter...',...
                                    'Input Error', 'modal') );
                        set(hObject,'Value',0);
                    else
                        % Change button text and open the serial port
                        set(hObject,'String','Close');
                        set(handles.machineStateTextBox,'String',["Machine State:","READY"]);
                        % store the device handle
                        handles.device = device;
                        guidata(hObject,handles);
            
                        % enable/disable GUI Elements
                        set(findall(handles.MotionButtonsGrp,...
                            '-property', 'enable'), 'enable', 'on');
                        set(findall(handles.commonCommandsGrp,...
                            '-property', 'enable'), 'enable', 'on');
                        set(handles.chooseFileButton,'enable','on');
                        set(handles.commandLine,'enable','on');
                        set(handles.probeCircleMenu,'enable','on');
                        set(handles.probeSpiralMenu,'enable','on');
                        set(handles.probeDotMenu,'enable','on');
                        set(handles.probeWindowMenu,'enable','on');
                        set(handles.millMenu,'enable','on');
                        set(handles.sucktionSpiralMenu,'enable','on');
                        set(handles.portMenu,'enable','off');
                        set(handles.refreshButton,'enable','off');
            
                        fprintf(device,'{"ej":1}'); % enter json mode
                        fprintf(device,'{"js":1}'); % strict json syntax (for TinyG)
                        fprintf(device,'{"jv":5}'); % verbose json feedback
                        fprintf(device,'{"sv":1}'); % enable filtered status reports
                    end
            
                % Else, if button is switched "off"
                else
                    % Delete serial instrument from memory; change button string
                    instrreset;
                    set(gcbo,'String','Open');
                    set(handles.machineStateTextBox,'String',["Machine State:","DISCONNECTED"]);
            
                    % enable/disable GUI Elements
                    set(findall(handles.MotionButtonsGrp,...
                        '-property', 'enable'), 'enable', 'off');
                    set(findall(handles.commonCommandsGrp,...
                        '-property', 'enable'), 'enable', 'off');
                    set(findall(handles.fileManagerGrp,...
                            '-property', 'enable'), 'enable', 'off');
                    set(handles.commandLine,'enable','off');
                    set(handles.probeCircleMenu,'enable','off');
                    set(handles.probeSpiralMenu,'enable','off');
                    set(handles.probeDotMenu,'enable','off');
                    set(handles.probeWindowMenu,'enable','off');
                    set(handles.millMenu,'enable','off');
                    set(handles.sucktionSpiralMenu,'enable','off');
                    set(handles.portMenu,'enable','on');
                    set(handles.refreshButton,'enable','on');
                end
                %save changes in data structure
            catch ME
                data = cellstr(get(handles.consoleWindow,'String'));
                data{end+1} = ME.identifier;
                data{end+1} = "Serial object appears to be busy, try again";
                set(handles.consoleWindow,'String',data,...
                             'Value',length(data));
                set(handles.connectButton,'Value',0);
                instrreset;
            end
            
            guidata(hObject,handles);
        end

        % Button pushed function: drill_replace
        function drill_replace_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to drill_replace (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            fprintf(handles.device,' G21; (change to mm)');
            fprintf(handles.device,' G90 G0 Z15; (Move the Drill Higher)');
            fprintf(handles.device,' G90 G0 Y-30; (Move the Drill Higher)');
            fprintf(handles.device,' G90 G0 X-120; (Move the Drill to the side)');
        end

        % Menu selected function: editSet
        function editSet_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editSet (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % create new window
            fig1 = figure('Name','Probed Points',...
                           'Units','pixels',...
                           'Position',[200,200,200,600],...
                           'NumberTitle','off',...
                           'Tag','pointList',...
                           'MenuBar','none',...
                           'ToolBar','none');
            fig1Handles = guidata(fig1);
            table = uitable('Parent', fig1,...
                            'Position', [0 0 200 575],...
                            'ColumnName',{'X','Y','Z'},...
                            'ColumnWidth',{50,50,50},...
                            'ColumnFormat',{'numeric','numeric','numeric'},...
                            'ColumnEditable',[true,true,true],...
                            'CellEditCallback',@toolTableEdit,...
                            'RowName',[],...
                            'Data',handles.skullPoints);
            
            fig2 = figure('Name','Point Mapping',...
                          'Units','pixels',...
                          'Position',[400,200,600,600],...
                          'NumberTitle','off',...
                          'Tag','pointMap');
            fig2Handles = guidata(fig2);
            X = handles.skullPoints(:,1);
            Y = handles.skullPoints(:,2);
            Z = handles.skullPoints(:,3);
            scatter3(X,Y,Z);
            axis equal
            xlabel('X-axis Location (mm)');
            ylabel('Y-axis Location (mm)');
            zlabel('Z-axis Location (mm)');
            title(sprintf('%d Total Points',length(X)));
            
                function toolTableEdit(~,eventdata,~)
                    % Save changes
                    figure(fig1);
                    row   = eventdata.Indices(1);
                    col   = eventdata.Indices(2);
                    input = eventdata.EditData;
                    if ~isnumeric(input)
                        input = str2double(input);
                    end
                    handles.skullPoints(row,col) = input;
                    % save any changes
                    guidata(hObject,handles);
            
                    figure(fig2);
                    X = handles.skullPoints(:,1);
                    Y = handles.skullPoints(:,2);
                    Z = handles.skullPoints(:,3);
                    scatter3(X,Y,Z);
                    axis equal
                    xlabel('X-axis Location (mm)');
                    ylabel('Y-axis Location (mm)');
                    zlabel('Z-axis Location (mm)');
                    title(sprintf('%d Total Points',length(X)));
                end
        end

        % Button pushed function: homeAllAxes
        function homeAllAxes_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Objective: Homes all axes and sets values to 0
            
            % hObject    handle to homeAllAxes (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            fprintf(handles.device,'G38.2 Z-90 F100; (Z Auto Down)'); %Z auto down while probing
        end

        % Selection changed function: linearStepGrp
        function linearStepGrp_SelectionChangedFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Objective: creates the button group that holds the units and step size
            % buttons. Its just used for ease of moving the button group in the future.
            
            % hObject    handle to the selected object in linearStepGrp
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if get(handles.MillimeterButton,'Value')
                fprintf(handles.device,'G21');
                handles.units = 1;
            else
                fprintf(handles.device,'G20');
                handles.units = 0;
            end
            guidata(hObject,handles);
        end

        % Value changed function: linearStepSize
        function linearStepSize_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Objective: This callback doesn't do anything since all that is needed is the string
            % stored in the textbox, which is used in the jogging buttons. But don't delete
            % this!
            
            % hObject    handle to linearStepSize (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of linearStepSize as text
            %        str2double(get(hObject,'String')) returns contents of linearStepSize as a double
            str=get(hObject,'String');
            if isempty(str2double(str))
                set(hObject,'string',1);
            end
        end

        % Menu selected function: millMenu
        function millMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Objective: create window to input milling parameters and generate gcode script
            
            % hObject    handle to millMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % create new window
            fig = figure('Name','Milling Parameters',...
                'Units','pixels',...
                'Position',[200,200,400,180],...
                'NumberTitle','off',...
                'Tag','millWindow',...
                'MenuBar','none',...
                'ToolBar','none');
            figHandles = guidata(fig);
            
            % create window elements
            figHandles.depthLabel = uicontrol(fig,'Style','text',...
                'Units','pixels',...
                'String','Depth per Pass (mm)',...
                'Position',[5,140,150,20]);
            figHandles.depthTextBox = uicontrol(fig,'Style','edit',...
                'Units','pixels',...
                'Tag','depthTextBox',...
                'String','0.05',...
                'Position',[200,140,50,20]);
            
            figHandles.thicknessLabel = uicontrol(fig,'Style','text',...
                'Units','pixels',...
                'String','Skull Thickness (mm)',...
                'Position',[5,110,150,20]);
            figHandles.thicknessTextBox = uicontrol(fig,'Style','edit',...
                'Units','pixels',...
                'Tag','thicknessTextBox',...
                'String','1',...
                'Position',[200,110,50,20]);
            
            figHandles.feedrateLabel = uicontrol(fig,'Style','text',...
                'Units','pixels',...
                'String','Feedrate (mm/min)',...
                'Position',[5,80,150,20]);
            figHandles.feedrateTextBox = uicontrol(fig,'Style','edit',...
                'Units','pixels',...
                'String','20',...
                'Position',[200,80,100,20]);
            
            figHandles.millSkullButton = uicontrol(fig,'Style','pushbutton',...
                'Units','pixels',...
                'Position',[100,20,200,40],...
                'String','Generate Milling Program',...
                'Callback',@app.millSkullButton_Callback);
            
            guidata(fig,figHandles);
        end

        % Button pushed function: moveToOriginButton
        function moveToOriginButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Objective: Moves all axes to their home position
            
            % hObject    handle to moveToOriginButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % device     handle to serial device (Craniobot)
            
            fprintf(handles.device,' G90 G0 Z15; (Move to XY origin)');
            fprintf(handles.device,' G90 G0 Y-30; (Move to XY origin)');
            fprintf(handles.device,' G90 G0 X0; (Move to XY origin)');
            fprintf(handles.device,' G90 G0 Y0; (Move to Z origin)');
        end

        % Value changed function: pauseButton
        function pauseButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Objective: Change the button's text based on its state
            
            % hObject    handle to pauseButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if get(gcbo,'Value')
                set(gcbo,'String','Resume');
                fprintf(handles.device,'!');
            else
                set(gcbo,'String','Pause');
                fprintf(handles.device,'~');
            end
        end

        % Button pushed function: plate_replace
        function plate_replace_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to plate_replace (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            fprintf(handles.device,' G21; (change to mm)');
            fprintf(handles.device,' G90 G0 Z15; (Move the Drill Higher)');
            fprintf(handles.device,' G90 G0 Y-30; (Move the Drill Higher)');
            fprintf(handles.device,' G90 G0 X-120; (Move the Drill/plate to the side)');
            fprintf(handles.device,' G90 G0 Y-60; (Move the Drill Higher)');
        end

        % Value changed function: portMenu
        function portMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Objective: Allows user to specify which serial object to connect to
            
            % hObject    handle to portMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % portlist   array of all serial object connected to computer
            % port       The serial port to communicate with
            
            portList = get(hObject,'String');
            % convert to string. If only one line is present in portList, portList(1) will
            % just return the first letter of the device, not the first line
            portList = string(portList);
            handles.port = portList(get(hObject,'Value'));
            guidata(gcf,handles);
        end

        % Menu selected function: probeCircleMenu
        function probeCircleMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Objective: create window to input probing parameters and generate gcode script
            % hObject    handle to probeCircleMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % create new window
            fig = figure('Name','Probing Parameters',...
                'Units','pixels',...
                'Position',[200,200,400,150],...
                'NumberTitle','off',...
                'Tag','ProbeCircleWindow',...
                'MenuBar','none',...
                'ToolBar','none');
            figHandles = guidata(fig);
            handles.skullPoints = [];
            guidata(hObject,handles);
            % create window elements
            figHandles.chamberDiameterLabel = uicontrol(fig,'Style','text',...
                'Units','pixels',...
                'String','Chamber Diameter (mm)',...
                'Position',[5,120,150,20]);
            figHandles.chamberDiameterTextBox = uicontrol(fig,'Style','edit',...
                'Units','pixels',...
                'Tag','chamberDiameterTextBox',...
                'String','3',...
                'Position',[200,120,50,20]);
            figHandles.chamberLocationLabel = uicontrol(fig,'Style','text',...
                'Units','pixels',...
                'String','Chamber Location (X,Y,Z) (mm)',...
                'Position',[5,90,150,20]);
            figHandles.chamberXTextBox = uicontrol(fig,'Style','edit',...
                'Units','pixels',...
                'Tag','chamberXTextBox',...
                'String','1',...
                'Position',[200,90,30,20]);
            figHandles.chamberYTextBox = uicontrol(fig,'Style','edit',...
                'Units','pixels',...
                'String','-1',...
                'Position',[240,90,30,20]);
            figHandles.chamberZTextBox = uicontrol(fig,'Style','edit',...
                'Units','pixels',...
                'String','0',...
                'Position',[280,90,30,20]);
            
            figHandles.probeSpeedLabel = uicontrol(fig,'Style','text',...
                'Units','pixels',...
                'String','Probe Speed (mm/min)',...
                'Position',[5,60,150,20]);
            figHandles.probeSpeedTextBox = uicontrol(fig,'Style','edit',...
                'Units','pixels',...
                'String','30',...
                'Position',[200,60,100,20]);
            
            figHandles.probeSkullButton = uicontrol(fig,'Style','pushbutton',...
                'Units','pixels',...
                'Position',[100,5,200,40],...
                'String','Generate Chamber Probing Program',...
                'Callback',@app.probeCircleButton_Callback);
            
            guidata(fig,figHandles);
        end

        % Menu selected function: probeDotMenu
        function probeDotMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Objective: create window to input probing parameters and generate gcode script
            % hObject    handle to probeCircleMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % create new window
            fig = figure('Name','Probing Parameters',...
                'Units','pixels',...
                'Position',[200,200,400,150],...
                'NumberTitle','off',...
                'Tag','ProbeDotWindow',...
                'MenuBar','none',...
                'ToolBar','none');
            figHandles = guidata(fig);
            handles.skullPoints = [];
            guidata(hObject,handles);
            % create window elements
            figHandles.chamberLocationDLabel = uicontrol(fig,'Style','text',...
                'Units','pixels',...
                'String','Chamber Location (X,Y,Z) (mm)',...
                'Position',[5,90,150,20]);
            figHandles.chamberXDTextBox = uicontrol(fig,'Style','edit',...
                'Units','pixels',...
                'Tag','chamberXTextBox',...
                'String','1',...
                'Position',[200,90,30,20]);
            figHandles.chamberYDTextBox = uicontrol(fig,'Style','edit',...
                'Units','pixels',...
                'String','-1',...
                'Position',[240,90,30,20]);
            figHandles.chamberZDTextBox = uicontrol(fig,'Style','edit',...
                'Units','pixels',...
                'String','0',...
                'Position',[280,90,30,20]);
            
            figHandles.probeSpeedDLabel = uicontrol(fig,'Style','text',...
                'Units','pixels',...
                'String','Probe Speed (mm/min)',...
                'Position',[5,60,150,20]);
            figHandles.probeSpeedDTextBox = uicontrol(fig,'Style','edit',...
                'Units','pixels',...
                'String','30',...
                'Position',[200,60,100,20]);
            figHandles.probeSkullDButton = uicontrol(fig,'Style','pushbutton',...
                'Units','pixels',...
                'Position',[100,5,200,40],...
                'String','Generate Chamber Probing Program',...
                'Callback',@app.probeDotButton_Callback);
            guidata(fig,figHandles);
        end

        % Menu selected function: probeSpiralMenu
        function probeSpiralMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Objective: create window to input probing parameters and generate gcode script
            % hObject    handle to probeCircleMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % create new window
            fig = figure('Name','Probing Parameters',...
                'Units','pixels',...
                'Position',[200,200,400,150],...
                'NumberTitle','off',...
                'Tag','ProbeSpiralWindow',...
                'MenuBar','none',...
                'ToolBar','none');
            figHandles = guidata(fig);
            handles.skullPoints = [];
            guidata(hObject,handles);
            % create window elements
            figHandles.chamberDiameterSLabel = uicontrol(fig,'Style','text',...
                'Units','pixels',...
                'String','Chamber/Niddle Diameter (mm)',...
                'Position',[5,120,150,20]);
            figHandles.chamberDiameterSTextBox = uicontrol(fig,'Style','edit',...
                'Units','pixels',...
                'Tag','chamberDiameterTextBox',...
                'String','1.4',...
                'Position',[200,120,50,20]);
            figHandles.chamberSuckTextBox = uicontrol(fig,'Style','edit',...
                'Units','pixels',...
                'String','0.65',...
                'Position',[260,120,50,20]);
            figHandles.chamberLocationSLabel = uicontrol(fig,'Style','text',...
                'Units','pixels',...
                'String','Chamber Location (X,Y,Z) (mm)',...
                'Position',[5,90,150,20]);
            figHandles.chamberXSTextBox = uicontrol(fig,'Style','edit',...
                'Units','pixels',...
                'Tag','chamberXTextBox',...
                'String','1',...
                'Position',[200,90,30,20]);
            figHandles.chamberYSTextBox = uicontrol(fig,'Style','edit',...
                'Units','pixels',...
                'String','-1',...
                'Position',[240,90,30,20]);
            figHandles.chamberZSTextBox = uicontrol(fig,'Style','edit',...
                'Units','pixels',...
                'String','0',...
                'Position',[280,90,30,20]);
            
            figHandles.probeSpeedSLabel = uicontrol(fig,'Style','text',...
                'Units','pixels',...
                'String','Probe Speed (mm/min)',...
                'Position',[5,60,150,20]);
            figHandles.probeSpeedSTextBox = uicontrol(fig,'Style','edit',...
                'Units','pixels',...
                'String','30',...
                'Position',[200,60,100,20]);
            
            figHandles.probeSkullSButton = uicontrol(fig,'Style','pushbutton',...
                'Units','pixels',...
                'Position',[100,5,200,40],...
                'String','Generate Chamber Probing Program',...
                'Callback',@app.probeSpiralButton_Callback);
            
            guidata(fig,figHandles);
        end

        % Menu selected function: probeWindowMenu
        function probeWindowMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to probeWindowMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            handles.skullPoints = [];
            guidata(hObject,handles);
            fig = figure('Name','Probing Parameters',...
                'Units','pixels',...
                'Position',[200,200,400,100],...
                'NumberTitle','off',...
                'Tag','ProbeWindowWindow',...
                'MenuBar','none',...
                'ToolBar','none');
            figHandles = guidata(fig);
            
            % create window elements
            figHandles.maxStepLabel = uicontrol(fig,'Style','text',...
                'Units','pixels',...
                'String','Max Step Size (mm)',...
                'Position',[5,60,150,20]);
            figHandles.maxStepTextBox = uicontrol(fig,'Style','edit',...
                'Units','pixels',...
                'Tag','maxStepTextBox',...
                'String','10',...
                'Position',[200,60,50,20]);
            figHandles.probeSkullButton = uicontrol(fig,'Style','pushbutton',...
                'Units','pixels',...
                'Position',[100,5,200,40],...
                'String','Generate Window Probing Program',...
                'Callback',@app.probeWindowButton_Callback);
            
            guidata(fig,figHandles);
        end

        % Button pushed function: refreshButton
        function refreshButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Objective: refreshes the list of serial objects connected to the computer
            
            % hObject    handle to refreshButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            set(handles.portMenu,'String',seriallist,'Value',1);
        end

        % Button pushed function: resetButton
        function resetButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to resetButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Send reset command to controller
            % Note: 24 is the ASCII character for ctrl-x
            fprintf(handles.device,24);
            data = cellstr(get(handles.consoleWindow,'String'));
            data{end+1} = "Resetting Machine..."; %concatenate newData with the old data
            set(handles.consoleWindow,'String',data,...
                       'Value',length(data));
        end

        % Button pushed function: resetXButton
        function resetXButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Objective: Set the current X axis position to 0
            % hObject    handle to resetXButton (see GCBO)
            
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % device     handle to serial device (Craniobot)
            
            % Set controller position
            fprintf(handles.device,'G28.3 X0; (Reset X Axis)');
        end

        % Button pushed function: resetYButton
        function resetYButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to resetYButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Set controller position
            fprintf(handles.device,'G28.3 Y0; (Reset Y Axis)');
        end

        % Button pushed function: resetZButton
        function resetZButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to resetZButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Set controller position
            fprintf(handles.device,'G28.3 Z0; (Reset Z Axis)');
        end

        % Button pushed function: sendFileButton
        function sendFileButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Objective: Send a G-code file to the Crabiobot using Linemode
            % protocol (see g2core wiki).
            
            GUI = findobj(0,'Tag','GUI');
            % enable/disable certain features
            set(hObject,'enable','off');
            set(findall(handles.MotionButtonsGrp,...
                '-property', 'enable'), 'enable', 'off');
            set(findall(handles.commonCommandsGrp,...
                '-property', 'enable'), 'enable', 'off');
            set(handles.commandLine,'enable','off');
            set(handles.pauseButton,'enable','on');
            set(handles.abortButton,'enable','on');
            % restart line count
            handles.line = 0;
            % open g-code file
            prgmFile = fopen(handles.filePath);
            probeFile = false;
            set(gcbo,'userdata',4);
            
            % change procedure slightly if running a probing file
            if contains(handles.filePath,'probe')
                probeFile = true;
                set(gcbo,'userdata',1);
                handles = guidata(findobj(0,'Tag','GUI')); % get handles
                guidata(GUI,handles);
            end
            % Begin file stream using Linemode Protocol
            % Continue protocol unless the file ends or the cancel button is pressed.
            % The number of lines to be send to the Craniobot is stored in the Send Button's
            % 'userdata' variable.
            command = {};
            while ~feof(prgmFile) && ~get(handles.abortButton,'Value')
                if probeFile
                    while (get(gcbo,'userdata') > 0) && ~get(handles.pauseButton,'Value') && ~feof(prgmFile)% && ~handles.lnRead
                        % send line
                        command = fgetl(prgmFile);
                        fprintf(handles.device,command);
                        if feof(prgmFile)
                            break;
                        end
                        set(gcbo,'userdata',get(gcbo,'userdata')-1);
                        drawnow();
                    end
                    drawnow();
                else
                    while (get(gcbo,'userdata') > 0) && ~get(handles.pauseButton,'Value') && ~feof(prgmFile)
                        % send line
                        command = fgetl(prgmFile);
                        fprintf(handles.device,command);
                        if feof(prgmFile)
                            break;
                        end
                        % decrement number of lines to be sent to arduino
                        set(gcbo,'userdata',get(gcbo,'userdata')-1);
                        drawnow();
                    end
                    drawnow();
                end
            end
            fclose(prgmFile);
            set(gcbo,'enable','on','Value',0); % reset Send File button
            set(handles.abortButton,'Value',0);
            % re-enable/disable control buttons when connected
            set(findall(handles.MotionButtonsGrp,...
                '-property', 'enable'), 'enable', 'on');
            set(findall(handles.commonCommandsGrp,...
                '-property', 'enable'), 'enable', 'on');
            set(handles.commandLine,'enable','on');
            set(handles.pauseButton,'enable','off');
            set(handles.abortButton,'enable','off');
        end

        % Button pushed function: setOriginButton
        function setOriginButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Objective: Sets current position to 0 for all axes
            
            % hObject    handle to setOriginButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % device     handle to serial device (Craniobot)
            
            fprintf(handles.device,'G28.3 X0 Y0 Z0 A0 B0 C0; (Set Origin)');
        end

        % Menu selected function: sucktionSpiralMenu
        function sucktionSpiralMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to sucktionSpiralMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %guidata(hObject,handles);
            % create new window
            fig = figure('Name','Sucktion Parameters',...
                'Units','pixels',...
                'Position',[200,200,400,180],...
                'NumberTitle','off',...
                'Tag','suckWindow',...
                'MenuBar','none',...
                'ToolBar','none');
            figHandles = guidata(fig);
            % create window elements
            figHandles.SchamberDiameterSLabel = uicontrol(fig,'Style','text',...
                'Units','pixels',...
                'String','Chamber/Niddle Diameter (mm)',...
                'Position',[5,150,150,20]);
            figHandles.SchamberDiameterSTextBox = uicontrol(fig,'Style','edit',...
                'Units','pixels',...
                'Tag','chamberDiameterTextBox',...
                'String','1.4',...
                'Position',[200,150,50,20]);
            figHandles.SchamberSuckTextBox = uicontrol(fig,'Style','edit',...
                'Units','pixels',...
                'String','0.65',...
                'Position',[260,150,50,20]);
            figHandles.SdepthLabel = uicontrol(fig,'Style','text',...
                'Units','pixels',...
                'String','Depth per Pass (mm)',...
                'Position',[5,120,150,20]);
            figHandles.SdepthTextBox = uicontrol(fig,'Style','edit',...
                'Units','pixels',...
                'Tag','depthTextBox',...
                'String','0.2',...
                'Position',[200,120,50,20]);
            
            figHandles.SthicknessLabel = uicontrol(fig,'Style','text',...
                'Units','pixels',...
                'String','Skull Thickness (mm)',...
                'Position',[5,90,150,20]);
            figHandles.SthicknessTextBox = uicontrol(fig,'Style','edit',...
                'Units','pixels',...
                'Tag','thicknessTextBox',...
                'String','2',...
                'Position',[200,90,50,20]);
            
            figHandles.SfeedrateLabel = uicontrol(fig,'Style','text',...
                'Units','pixels',...
                'String','Feedrate (mm/min)',...
                'Position',[5,60,150,20]);
            figHandles.SfeedrateTextBox = uicontrol(fig,'Style','edit',...
                'Units','pixels',...
                'String','20',...
                'Position',[200,60,100,20]);
            
            figHandles.spiralSkullButton = uicontrol(fig,'Style','pushbutton',...
                'Units','pixels',...
                'Position',[100,10,200,40],...
                'String','Generate Sucktion Program',...
                'Callback',@app.sucktionSpiralButton_Callback);
            
            guidata(fig,figHandles);
        end

        % Menu selected function: toolMenu
        function toolMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Objective: Allow user to see/change all tool ofsets
            % hObject    handle to toolMenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % create new window
            fig = figure('Name','Tool Table',...
                'Units','pixels',...
                'Position',[200,200,385,575],...
                'NumberTitle','off',...
                'Tag','millWindow',...
                'MenuBar','none',...
                'ToolBar','none');
            
            % get table
            filename = fullfile(pwd,'toolTable.csv');
            handles.toolTable = readtable(filename);
            tData = table2cell(handles.toolTable);
            table = uitable('Parent', fig,...
                'Position', [0 0 385 575],...
                'ColumnName',handles.toolTable.Properties.VariableNames,...
                'ColumnWidth',{100,200,80},...
                'ColumnEditable',[false,true,true],...
                'CellEditCallback',@toolTableEdit,...
                'RowName',[],...
                'Data',tData);
            
                function toolTableEdit(~,eventdata,~)
                    % Save changes to csv file
                    row = eventdata.Indices(1);
                    col = eventdata.Indices(2);
                    input = eventdata.EditData;
                    if col == 3
                        input = str2double(input);
                    end
                    handles.toolTable(row,col) = {input};
                    writetable(handles.toolTable,filename);
                end
            % save any changes
            guidata(hObject,handles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GUI = uifigure('Visible', 'off');
            app.GUI.Name = 'CraniobotCourier';
            app.GUI.WindowKeyPressFcn = createCallbackFcn(app, @GUI_WindowKeyPressFcn, true);
            app.GUI.Tag = 'GUI';
            app.ProbingMenu = uimenu(app.GUI);
            app.ProbingMenu.MenuSelectedFcn = createCallbackFcn(app, @ProbingMenu_Callback, true);
            app.ProbingMenu.Text = 'Probing';
            app.ProbingMenu.Tag = 'ProbingMenu';
            app.probeCircleMenu = uimenu(app.ProbingMenu);
            app.probeCircleMenu.MenuSelectedFcn = createCallbackFcn(app, @probeCircleMenu_Callback, true);
            app.probeCircleMenu.Enable = 'off';
            app.probeCircleMenu.Text = 'Probe Circle';
            app.probeCircleMenu.Tag = 'probeCircleMenu';
            app.probeSpiralMenu = uimenu(app.ProbingMenu);
            app.probeSpiralMenu.MenuSelectedFcn = createCallbackFcn(app, @probeSpiralMenu_Callback, true);
            app.probeSpiralMenu.Enable = 'off';
            app.probeSpiralMenu.Text = 'Probe Spiral';
            app.probeSpiralMenu.Tag = 'probeSpiralMenu';
            app.probeDotMenu = uimenu(app.ProbingMenu);
            app.probeDotMenu.MenuSelectedFcn = createCallbackFcn(app, @probeDotMenu_Callback, true);
            app.probeDotMenu.Enable = 'off';
            app.probeDotMenu.Text = 'Probe Dot';
            app.probeDotMenu.Tag = 'probeDotMenu';
            app.probeWindowMenu = uimenu(app.ProbingMenu);
            app.probeWindowMenu.MenuSelectedFcn = createCallbackFcn(app, @probeWindowMenu_Callback, true);
            app.probeWindowMenu.Enable = 'off';
            app.probeWindowMenu.Text = 'Probe Window';
            app.probeWindowMenu.Tag = 'probeWindowMenu';
            app.clearSet = uimenu(app.ProbingMenu);
            app.clearSet.MenuSelectedFcn = createCallbackFcn(app, @clearSet_Callback, true);
            app.clearSet.Separator = 'on';
            app.clearSet.Text = 'Clear Set';
            app.clearSet.Tag = 'clearSet';
            app.editSet = uimenu(app.ProbingMenu);
            app.editSet.MenuSelectedFcn = createCallbackFcn(app, @editSet_Callback, true);
            app.editSet.Separator = 'on';
            app.editSet.Text = 'Edit Set';
            app.editSet.Tag = 'editSet';
            app.MillingMenu = uimenu(app.GUI);
            app.MillingMenu.Text = 'Milling';
            app.MillingMenu.Tag = 'MillingMenu';
            app.millMenu = uimenu(app.MillingMenu);
            app.millMenu.MenuSelectedFcn = createCallbackFcn(app, @millMenu_Callback, true);
            app.millMenu.Enable = 'off';
            app.millMenu.Text = 'Mill Probed Points';
            app.millMenu.Tag = 'millMenu';
            app.SucktionMenu = uimenu(app.GUI);
            app.SucktionMenu.Text = 'Sucktion';
            app.SucktionMenu.Tag = 'SucktionMenu';
            app.sucktionSpiralMenu = uimenu(app.SucktionMenu);
            app.sucktionSpiralMenu.MenuSelectedFcn = createCallbackFcn(app, @sucktionSpiralMenu_Callback, true);
            app.sucktionSpiralMenu.Enable = 'off';
            app.sucktionSpiralMenu.Text = 'Sucktion Spiral';
            app.sucktionSpiralMenu.Tag = 'sucktionSpiralMenu';
            app.toolMenu = uimenu(app.GUI);
            app.toolMenu.MenuSelectedFcn = createCallbackFcn(app, @toolMenu_Callback, true);
            app.toolMenu.Text = 'Tool Table';
            app.toolMenu.Tag = 'toolMenu';
            app.connectionPanel = uipanel(app.GUI);
            app.connectionPanel.Title = 'Connection';
            app.connectionPanel.Tag = 'connectionPanel';
            app.portMenu = uidropdown(app.connectionPanel);
            app.portMenu.Items = {'COM3', 'COM4', 'COM5', 'COM6'};
            app.portMenu.ValueChangedFcn = createCallbackFcn(app, @portMenu_Callback, true);
            app.portMenu.Tag = 'portMenu';
            app.portMenu.Value = 'COM3';
            app.portTextBox = uilabel(app.connectionPanel);
            app.portTextBox.Tag = 'portTextBox';
            app.portTextBox.Text = 'Port:';
            app.connectButton = uibutton(app.connectionPanel, 'state');
            app.connectButton.ValueChangedFcn = createCallbackFcn(app, @connectButton_Callback, true);
            app.connectButton.Tag = 'connectButton';
            app.connectButton.Text = 'Open';
            app.refreshButton = uibutton(app.connectionPanel, 'push');
            app.refreshButton.ButtonPushedFcn = createCallbackFcn(app, @refreshButton_Callback, true);
            app.refreshButton.Tag = 'refreshButton';
            app.refreshButton.Text = 'Refresh';
            app.consoleWindow = uilistbox(app.GUI);
            app.consoleWindow.Items = {};
            app.consoleWindow.Multiselect = 'on';
            app.consoleWindow.Tag = 'consoleWindow';
            app.consoleWindow.Enable = 'off';
            app.consoleWindow.Value = {};
            app.commandLine = uieditfield(app.GUI, 'text');
            app.commandLine.ValueChangedFcn = createCallbackFcn(app, @commandLine_Callback, true);
            app.commandLine.Tag = 'commandLine';
            app.commandLine.Enable = 'off';
            app.commandLineTextBox = uilabel(app.GUI);
            app.commandLineTextBox.Tag = 'commandLineTextBox';
            app.commandLineTextBox.Text = 'Command:';
            app.MotionButtonsGrp = uipanel(app.GUI);
            app.MotionButtonsGrp.Title = 'Manual Jog';
            app.MotionButtonsGrp.Tag = 'MotionButtonsGrp';
            app.linearStepGrp = uibuttongroup(app.MotionButtonsGrp);
            app.linearStepGrp.SelectionChangedFcn = createCallbackFcn(app, @linearStepGrp_SelectionChangedFcn, true);
            app.linearStepGrp.Tag = 'linearStepGrp';
            app.linearStepSizeTextBox = uilabel(app.linearStepGrp);
            app.linearStepSizeTextBox.Tag = 'linearStepSizeTextBox';
            app.linearStepSizeTextBox.Text = 'Step Size: ';
            app.linearStepSize = uieditfield(app.linearStepGrp, 'text');
            app.linearStepSize.ValueChangedFcn = createCallbackFcn(app, @linearStepSize_Callback, true);
            app.linearStepSize.Tag = 'linearStepSize';
            app.linearStepSize.Enable = 'off';
            app.linearStepSize.Value = '1';
            app.MillimeterButton = uiradiobutton(app.linearStepGrp);
            app.MillimeterButton.Tag = 'MillimeterButton';
            app.MillimeterButton.Enable = 'off';
            app.MillimeterButton.Text = 'mm';
            app.MillimeterButton.Value = true;
            app.InchesButton = uiradiobutton(app.linearStepGrp);
            app.InchesButton.Tag = 'InchesButton';
            app.InchesButton.Enable = 'off';
            app.InchesButton.Text = 'Inche';
            app.XPlus = uibutton(app.MotionButtonsGrp, 'push');
            app.XPlus.ButtonPushedFcn = createCallbackFcn(app, @XPlus_Callback, true);
            app.XPlus.Tag = 'XPlus';
            app.XPlus.Enable = 'off';
            app.XPlus.Text = 'X+';
            app.XMinus = uibutton(app.MotionButtonsGrp, 'push');
            app.XMinus.ButtonPushedFcn = createCallbackFcn(app, @XMinus_Callback, true);
            app.XMinus.Tag = 'XMinus';
            app.XMinus.Enable = 'off';
            app.XMinus.Text = 'X-';
            app.YPlus = uibutton(app.MotionButtonsGrp, 'push');
            app.YPlus.ButtonPushedFcn = createCallbackFcn(app, @YPlus_Callback, true);
            app.YPlus.Tag = 'YPlus';
            app.YPlus.Enable = 'off';
            app.YPlus.Text = 'Y+';
            app.YMinus = uibutton(app.MotionButtonsGrp, 'push');
            app.YMinus.ButtonPushedFcn = createCallbackFcn(app, @YMinus_Callback, true);
            app.YMinus.Tag = 'YMinus';
            app.YMinus.Enable = 'off';
            app.YMinus.Text = 'Y-';
            app.ZPlus = uibutton(app.MotionButtonsGrp, 'push');
            app.ZPlus.ButtonPushedFcn = createCallbackFcn(app, @ZPlus_Callback, true);
            app.ZPlus.Tag = 'ZPlus';
            app.ZPlus.Enable = 'off';
            app.ZPlus.Text = 'Z+';
            app.ZMinus = uibutton(app.MotionButtonsGrp, 'push');
            app.ZMinus.ButtonPushedFcn = createCallbackFcn(app, @ZMinus_Callback, true);
            app.ZMinus.Tag = 'ZMinus';
            app.ZMinus.Enable = 'off';
            app.ZMinus.Text = 'Z-';
            app.consoleTextBox = uilabel(app.GUI);
            app.consoleTextBox.Tag = 'consoleTextBox';
            app.consoleTextBox.Text = 'Console:';
            app.commonCommandsGrp = uipanel(app.GUI);
            app.commonCommandsGrp.Title = 'Common Commands';
            app.commonCommandsGrp.Tag = 'commonCommandsGrp';
            app.setOriginButton = uibutton(app.commonCommandsGrp, 'push');
            app.setOriginButton.ButtonPushedFcn = createCallbackFcn(app, @setOriginButton_Callback, true);
            app.setOriginButton.Tag = 'setOriginButton';
            app.setOriginButton.Enable = 'off';
            app.setOriginButton.Text = 'Set Origin';
            app.moveToOriginButton = uibutton(app.commonCommandsGrp, 'push');
            app.moveToOriginButton.ButtonPushedFcn = createCallbackFcn(app, @moveToOriginButton_Callback, true);
            app.moveToOriginButton.Tag = 'moveToOriginButton';
            app.moveToOriginButton.Enable = 'off';
            app.moveToOriginButton.Text = 'Move To Origin';
            app.resetXButton = uibutton(app.commonCommandsGrp, 'push');
            app.resetXButton.ButtonPushedFcn = createCallbackFcn(app, @resetXButton_Callback, true);
            app.resetXButton.Tag = 'resetXButton';
            app.resetXButton.Enable = 'off';
            app.resetXButton.Text = 'Reset X';
            app.resetYButton = uibutton(app.commonCommandsGrp, 'push');
            app.resetYButton.ButtonPushedFcn = createCallbackFcn(app, @resetYButton_Callback, true);
            app.resetYButton.Tag = 'resetYButton';
            app.resetYButton.Enable = 'off';
            app.resetYButton.Text = 'Reset Y';
            app.resetZButton = uibutton(app.commonCommandsGrp, 'push');
            app.resetZButton.ButtonPushedFcn = createCallbackFcn(app, @resetZButton_Callback, true);
            app.resetZButton.Tag = 'resetZButton';
            app.resetZButton.Enable = 'off';
            app.resetZButton.Text = 'Reset Z';
            app.homeAllAxes = uibutton(app.commonCommandsGrp, 'push');
            app.homeAllAxes.ButtonPushedFcn = createCallbackFcn(app, @homeAllAxes_Callback, true);
            app.homeAllAxes.Tag = 'homeAllAxes';
            app.homeAllAxes.Enable = 'off';
            app.homeAllAxes.Text = 'Z Auto Down';
            app.clearButton = uibutton(app.commonCommandsGrp, 'push');
            app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @clearButton_Callback, true);
            app.clearButton.Tag = 'clearButton';
            app.clearButton.Enable = 'off';
            app.clearButton.Text = 'Origin Upper 3mm';
            app.resetButton = uibutton(app.commonCommandsGrp, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButton_Callback, true);
            app.resetButton.Tag = 'resetButton';
            app.resetButton.Enable = 'off';
            app.resetButton.Text = 'Reset Machine';
            app.G55Button = uibutton(app.commonCommandsGrp, 'push');
            app.G55Button.ButtonPushedFcn = createCallbackFcn(app, @G55Button_Callback, true);
            app.G55Button.Tag = 'G55Button';
            app.G55Button.Enable = 'off';
            app.G55Button.Text = 'Set G55 Z Offset';
            app.G56Button = uibutton(app.commonCommandsGrp, 'push');
            app.G56Button.ButtonPushedFcn = createCallbackFcn(app, @G56Button_Callback, true);
            app.G56Button.Tag = 'G56Button';
            app.G56Button.Enable = 'off';
            app.G56Button.Text = 'Set G56 Z Offset';
            app.G55Text = uieditfield(app.commonCommandsGrp, 'text');
            app.G55Text.ValueChangedFcn = createCallbackFcn(app, @G55Text_Callback, true);
            app.G55Text.Tag = 'G55Text';
            app.G55Text.Enable = 'off';
            app.G55Text.Value = '0';
            app.G56Text = uieditfield(app.commonCommandsGrp, 'text');
            app.G56Text.ValueChangedFcn = createCallbackFcn(app, @G56Text_Callback, true);
            app.G56Text.Tag = 'G56Text';
            app.G56Text.Enable = 'off';
            app.G56Text.Value = '3';
            app.drill_replace = uibutton(app.commonCommandsGrp, 'push');
            app.drill_replace.ButtonPushedFcn = createCallbackFcn(app, @drill_replace_Callback, true);
            app.drill_replace.Tag = 'drill_replace';
            app.drill_replace.Enable = 'off';
            app.drill_replace.Text = 'Drill replace';
            app.plate_replace = uibutton(app.commonCommandsGrp, 'push');
            app.plate_replace.ButtonPushedFcn = createCallbackFcn(app, @plate_replace_Callback, true);
            app.plate_replace.Tag = 'plate_replace';
            app.plate_replace.Enable = 'off';
            app.plate_replace.Text = 'Plate replace';
            app.systemReportPanel = uipanel(app.GUI);
            app.systemReportPanel.Title = 'System Report';
            app.systemReportPanel.Tag = 'systemReportPanel';
            app.workPositionTextBox = uilabel(app.systemReportPanel);
            app.workPositionTextBox.Tag = 'workPositionTextBox';
            app.workPositionTextBox.Text = 'Work Position:';
            app.machineStateTextBox = uilabel(app.systemReportPanel);
            app.machineStateTextBox.Tag = 'machineStateTextBox';
            app.machineStateTextBox.Text = {'Machine State:'; 'DISCONNECTED'};
            app.clearWindow = uibutton(app.GUI, 'push');
            app.clearWindow.ButtonPushedFcn = createCallbackFcn(app, @clearWindow_Callback, true);
            app.clearWindow.Tag = 'clearWindow';
            app.clearWindow.Text = 'Clear';
            app.fileManagerGrp = uipanel(app.GUI);
            app.fileManagerGrp.Title = 'File Manager';
            app.fileManagerGrp.Tag = 'fileManagerGrp';
            app.chooseFileButton = uibutton(app.fileManagerGrp, 'push');
            app.chooseFileButton.ButtonPushedFcn = createCallbackFcn(app, @chooseFileButton_Callback, true);
            app.chooseFileButton.Tag = 'chooseFileButton';
            app.chooseFileButton.Enable = 'off';
            app.chooseFileButton.Text = 'Choose File';
            app.sendFileButton = uibutton(app.fileManagerGrp, 'push');
            app.sendFileButton.ButtonPushedFcn = createCallbackFcn(app, @sendFileButton_Callback, true);
            app.sendFileButton.Tag = 'sendFileButton';
            app.sendFileButton.Enable = 'off';
            app.sendFileButton.Text = 'Run';
            app.pauseButton = uibutton(app.fileManagerGrp, 'state');
            app.pauseButton.ValueChangedFcn = createCallbackFcn(app, @pauseButton_Callback, true);
            app.pauseButton.Tag = 'pauseButton';
            app.pauseButton.Enable = 'off';
            app.pauseButton.Text = 'Pause';
            app.abortButton = uibutton(app.fileManagerGrp, 'state');
            app.abortButton.ValueChangedFcn = createCallbackFcn(app, @abortButton_Callback, true);
            app.abortButton.Tag = 'abortButton';
            app.abortButton.Enable = 'off';
            app.abortButton.Text = 'Abort';
            app.fileTextBox = uilabel(app.fileManagerGrp);
            app.fileTextBox.Tag = 'fileTextBox';
            app.fileTextBox.Text = 'No File Selected';
            app.progressBar = uilabel(app.fileManagerGrp);
            app.progressBar.Tag = 'progressBar';
            app.progressBar.Text = {'Progress:'; '100%'};
            % Show the figure after all components are created
            app.GUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CraniobotCourier_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.GUI)

                % Execute the startup function
                runStartupFcn(app, @(app)CraniobotCourier_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.GUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GUI)
        end
    end
end